classdef MieCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ContactinformationMenu  matlab.ui.container.Menu
        toftulivangmailcomMenu  matlab.ui.container.Menu
        UIAxes                  matlab.ui.control.UIAxes
        LabelDropDown           matlab.ui.control.Label
        Geometry                matlab.ui.control.DropDown
        LabelSlider             matlab.ui.control.Label
        Radius                  matlab.ui.control.Slider
        LabelDropDown2          matlab.ui.control.Label
        Material                matlab.ui.control.DropDown
        wavelengthMin           matlab.ui.control.NumericEditField
        LabelNumericEditField   matlab.ui.control.Label
        LabelNumericEditField2  matlab.ui.control.Label
        wavelengthMax           matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Label2                  matlab.ui.control.Label
        Label3                  matlab.ui.control.Label
        Label4                  matlab.ui.control.Label
        RadiusBox               matlab.ui.control.NumericEditField
        LabelNumericEditField3  matlab.ui.control.Label
        LabelNumericEditField4  matlab.ui.control.Label
        MultipoleOrder          matlab.ui.control.NumericEditField
        ModeDecompositionExt    matlab.ui.control.CheckBox
        ModeDecompositionSc     matlab.ui.control.CheckBox
        CreatedbyToftulIDLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            qDecompositionExt = app.ModeDecompositionExt.Value;
            qDecompositionSc = app.ModeDecompositionSc.Value;
            
            value = app.RadiusBox.Value;
            numberOfPoints = 400;
            dwl = (app.wavelengthMax.Value - app.wavelengthMin.Value) / numberOfPoints;
            wlMin = min([app.wavelengthMax.Value app.wavelengthMin.Value]);
            wlMax = max([app.wavelengthMax.Value app.wavelengthMin.Value]);
            wlSpace = wlMin : dwl : wlMax;
            
            %----------Input  Parameters------------------------------------------------
            n = app.MultipoleOrder.Value; % Multipole order 
            r = value*1e-9; %[m] Sphere radius 
            S0 = 4*pi*r^2; % [m^2] geometrical cross section
            I = 1.3e-3; %[W] Incident wave intensity 
            lambda = wlSpace.*1e-9; %[m] Wavelength
            f = 3e8./lambda; %[Hz] frequency
            w=1.24e-6./lambda; % [eV] energy
            mu = 1; % magnetic permiability of medium
            mu1 = 1; % magnetic permiability of sphere
            N = 1; % refractive index of medium
            k = 2*pi*N./lambda; % [1/m] wavevector 
            %-------------------Material Parameters------------------------------------
            if strcmp(app.Material.Value, 'Silver') 
                material = 'Ag';
            elseif strcmp(app.Material.Value, 'Gold') 
                material = 'Au';
            elseif strcmp(app.Material.Value, 'Silica') 
                material = 'Si';
            elseif strcmp(app.Material.Value, 'Copper') 
                material = 'Cu';
            elseif strcmp(app.Material.Value, 'Aluminum')
                material = 'Al';
            elseif strcmp(app.Material.Value, 'Drude') 
                material = 'Dr';
            end
            epsi=EpsMat(w, material);
            %epsi=2.25;
            N1 = sqrt(epsi);
            %----------Calculating cross sections--------------------------------------
            %Scattering cross section
            Csca = 0;
            CscaE = 0;
            CscaM = 0;
            for i=1:n
                Csca = Csca + 2*pi./k.^2 .* (2.*i+1) .* (abs(An(i,mu,mu1,N,N1,r,lambda)).^2 ...
                + abs(Bn(i,mu,mu1,N,N1,r,lambda)).^2);
               % CscaE = CscaE + 2*pi./k.^2 .* (2.*i+1) .* (abs(An(i,mu,mu1,N,N1,r,lambda)).^2);
               % CscaM = CscaM + 2*pi./k.^2 .* (2.*i+1) .* (abs(Bn(i,mu,mu1,N,N1,r,lambda)).^2);
            end
            % Mode Decomposition part
            CscaEModes = zeros(3, length(wlSpace));
            CscaMModes = zeros(3, length(wlSpace));
            if qDecompositionSc
                for i=1:3
                    CscaEModes(i, :) = 2*pi./k.^2 .* (2.*i+1) .* abs(An(i,mu,mu1,N,N1,r,lambda)).^2;
                    CscaMModes(i, :) = 2*pi./k.^2 .* (2.*i+1) .* abs(Bn(i,mu,mu1,N,N1,r,lambda)).^2;
                end
            end
                
            %Extinction cross section
            Cext = 0;
            for i=1:1:n
                Cext = Cext + 2*pi./k.^2 .* (2.*i+1) .* real(An(i,mu,mu1,N,N1,r,lambda)...
                    + Bn(i,mu,mu1,N,N1,r,lambda));
            end
            % Mode Decomposition part
            CextEModes = zeros(3, length(wlSpace));
            CextMModes = zeros(3, length(wlSpace));
            if qDecompositionExt
                for i=1:3
                    CextEModes(i, :) = 2*pi./k.^2 .* (2.*i+1) .* real(An(i,mu,mu1,N,N1,r,lambda));
                    CextMModes(i, :) = 2*pi./k.^2 .* (2.*i+1) .* real(Bn(i,mu,mu1,N,N1,r,lambda));
                end
            end
            %-------------Plotting results--------------------------- 
            % clear axes
            cla(app.UIAxes)
            %hold(app.UIAxes, 'off');
            %plot(app.UIAxes, 1, 1)
            hold(app.UIAxes, 'on');
            % plotting
            app.UIAxes.XLim = [wlMin wlMax];
            %app.UIAxes.YLim = [0 10.0];
            plot(app.UIAxes, lambda*1e9,Csca/S0,'k','linewidth',2, 'DisplayName','Scattering');
            %plot(app.UIAxes, lambda*1e9,CscaE/pi/r^2,'r','linewidth',2);
            %plot(app.UIAxes, lambda*1e9,CscaM/pi/r^2,'b','linewidth',2);
            %legend(app.UIAxes, 'Scattering ','Extinction ', 'Total')
            plot(app.UIAxes, lambda*1e9,Cext/S0,'r','linewidth',2, 'DisplayName','Extinction');
            %xlabel('Wavelength, nm,','Fontsize',20);
            %ylabel('Scattering cross section, \mum^2 ','Fontsize',20);
            %legend(app.UIAxes, 'Scattering ','Extinction ');
            %set(gca,'fontsize',20)
            if qDecompositionSc
                for i=1:3
                    plot(app.UIAxes, lambda*1e9,CscaEModes(i, :)/S0, '--','linewidth',1, 'DisplayName',sprintf('ScE n=%d',i));
                    plot(app.UIAxes, lambda*1e9,CscaMModes(i, :)/S0, '-.','linewidth',1, 'DisplayName',sprintf('ScM n=%d',i));
                end
            end
            if qDecompositionExt
                for i=1:3
                    plot(app.UIAxes, lambda*1e9,CextEModes(i, :)/S0, '--','linewidth',1, 'DisplayName',sprintf('ExtE n=%d',i));
                    plot(app.UIAxes, lambda*1e9,CextMModes(i, :)/S0, '-.','linewidth',1, 'DisplayName',sprintf('ExtM n=%d',i));
                end
            end
            legend(app.UIAxes, 'Location','NorthEastOutside')
        end

        % Value changed function: Radius
        function RadiusValueChanged(app, event)
            app.RadiusBox.Value = app.Radius.Value; 
            startupFcn(app)
        end

        % Value changed function: RadiusBox
        function RadiusBoxValueChanged(app, event)
            app.Radius.Value = app.RadiusBox.Value; 
            startupFcn(app)
        end

        % Value changing function: Radius
        function RadiusValueChanging(app, event)
            app.RadiusBox.Value = event.Value; 
            startupFcn(app)
        end

        % Value changed function: Material
        function MaterialValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: wavelengthMax
        function wavelengthMaxValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: wavelengthMin
        function wavelengthMinValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: MultipoleOrder
        function MultipoleOrderValueChanged(app, event)
            startupFcn(app)            
        end

        % Value changed function: ModeDecompositionExt
        function ModeDecompositionExtValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: ModeDecompositionSc
        function ModeDecompositionScValueChanged(app, event)
            startupFcn(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ContactinformationMenu = uimenu(app.UIFigure);
            app.ContactinformationMenu.Text = 'Contact information';
            app.toftulivangmailcomMenu = uimenu(app.ContactinformationMenu);
            app.toftulivangmailcomMenu.Text = 'toftul.ivan@gmail.com';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Wavelength, nm')
            ylabel(app.UIAxes, 'Normalized scattering cross section')
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = {'Object'; 'Geometry'};
            app.Geometry = uidropdown(app.UIFigure);
            app.Geometry.Items = {'Sphere', 'Core-shell', 'Cylinder'};
            app.Geometry.Enable = 'off';
            app.Geometry.Value = 'Sphere';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Radius';
            app.Radius = uislider(app.UIFigure);
            app.Radius.Limits = [5 500];
            app.Radius.MajorTickLabels = {'5', '', '', '', '', '500'};
            app.Radius.ValueChangedFcn = createCallbackFcn(app, @RadiusValueChanged, true);
            app.Radius.ValueChangingFcn = createCallbackFcn(app, @RadiusValueChanging, true);
            app.Radius.Value = 30;
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = {'Object material'; 'ε(ω)​​'};
            app.Material = uidropdown(app.UIFigure);
            app.Material.Items = {'Gold', 'Silver', 'Copper', 'Aluminum', 'Silica'};
            app.Material.ValueChangedFcn = createCallbackFcn(app, @MaterialValueChanged, true);
            app.Material.Value = 'Gold';
            app.wavelengthMin = uieditfield(app.UIFigure, 'numeric');
            app.wavelengthMin.Limits = [300 2000];
            app.wavelengthMin.ValueChangedFcn = createCallbackFcn(app, @wavelengthMinValueChanged, true);
            app.wavelengthMin.Value = 300;
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'min';
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'max';
            app.wavelengthMax = uieditfield(app.UIFigure, 'numeric');
            app.wavelengthMax.Limits = [300 2000];
            app.wavelengthMax.ValueChangedFcn = createCallbackFcn(app, @wavelengthMaxValueChanged, true);
            app.wavelengthMax.Value = 900;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Wavelength'; 'limits: [300, 2000]'};
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'nm';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'nm';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Mie calculator';
            app.RadiusBox = uieditfield(app.UIFigure, 'numeric');
            app.RadiusBox.Limits = [10 500];
            app.RadiusBox.RoundFractionalValues = 'on';
            app.RadiusBox.ValueDisplayFormat = '%.0f';
            app.RadiusBox.ValueChangedFcn = createCallbackFcn(app, @RadiusBoxValueChanged, true);
            app.RadiusBox.Value = 100;
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'nm';
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Multipole order ';
            app.MultipoleOrder = uieditfield(app.UIFigure, 'numeric');
            app.MultipoleOrder.Limits = [1 100];
            app.MultipoleOrder.ValueChangedFcn = createCallbackFcn(app, @MultipoleOrderValueChanged, true);
            app.MultipoleOrder.Value = 20;
            app.ModeDecompositionExt = uicheckbox(app.UIFigure);
            app.ModeDecompositionExt.ValueChangedFcn = createCallbackFcn(app, @ModeDecompositionExtValueChanged, true);
            app.ModeDecompositionExt.Text = ' Mode decomposition for Ext';
            app.ModeDecompositionExt.Value = true;
            app.ModeDecompositionSc = uicheckbox(app.UIFigure);
            app.ModeDecompositionSc.ValueChangedFcn = createCallbackFcn(app, @ModeDecompositionScValueChanged, true);
            app.ModeDecompositionSc.Text = ' Mode decomposition for Sc';
            app.ModeDecompositionSc.Value = true;
            app.CreatedbyToftulIDLabel = uilabel(app.UIFigure);
            app.CreatedbyToftulIDLabel.Text = 'Created by Toftul I. D.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MieCalc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end