classdef SkinDepth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxesEps                   matlab.ui.control.UIAxes
        UIAxesDelta                 matlab.ui.control.UIAxes
        SkindepthcalculatorLabel    matlab.ui.control.Label
        LabelDropDown2              matlab.ui.control.Label
        Material                    matlab.ui.control.DropDown
        SpecificpointPanel          matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        EpsRe                       matlab.ui.control.NumericEditField
        MaterialpermittivityiLabel  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        EpsIm                       matlab.ui.control.NumericEditField
        Skindepth1Label             matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Delta                       matlab.ui.control.NumericEditField
        nmLabel                     matlab.ui.control.Label
        WavelengthLabel             matlab.ui.control.Label
        WavelengthBox               matlab.ui.control.Spinner
        nmLabel_2                   matlab.ui.control.Label
        ContactinformationMenu      matlab.ui.container.Menu
        toftulivangmailcomMenu      matlab.ui.container.Menu
        CreatedbyIvanToftulLabel    matlab.ui.control.Label
        PlotrangeLabel              matlab.ui.control.Label
        MinEditFieldLabel           matlab.ui.control.Label
        WLMIN                       matlab.ui.control.NumericEditField
        MinEditFieldLabel_2         matlab.ui.control.Label
        MinEditFieldLabel_3         matlab.ui.control.Label
        MaxEditFieldLabel           matlab.ui.control.Label
        WLMAX                       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.WavelengthBox.Value * 1e-9;
            numberOfPoints = 400;
            %dwl = (app.wavelengthMax.Value - app.wavelengthMin.Value) / numberOfPoints;
            wlMin = app.WLMIN.Value;
            wlMax = app.WLMAX.Value;
            wlMin = wlMin * 1e-9;  % [nm] -> [m]
            wlMax = wlMax * 1e-9;  % [nm] -> [m]
            dwl = (wlMax - wlMin) / numberOfPoints;
            wlSpace = wlMin : dwl : wlMax; 
            
            %-------------------Material Parameters------------------------------------
            if strcmp(app.Material.Value, 'Silver') 
                material = 'Ag';
            elseif strcmp(app.Material.Value, 'Gold') 
                material = 'Au';
            elseif strcmp(app.Material.Value, 'Silicon') 
                material = 'Si';
            elseif strcmp(app.Material.Value, 'Copper') 
                material = 'Cu';
            elseif strcmp(app.Material.Value, 'Aluminum')
                material = 'Al';
            elseif strcmp(app.Material.Value, 'Drude') 
                material = 'Dr';
            end
            
            %-----------Data-----------------------------
            w = 1.24e-6./wlSpace;  % [eV] energy
            epsi = EpsMat(w, material);
            nIm = imag(sqrt(epsi));
            delta = wlSpace ./ (4*pi*nIm);
            
            epsRe = real(epsi);
            epsIm = imag(epsi);
            
            pointEpsRe = real(EpsMat(1.24e-6./value, material));
            pointEpsIm = imag(EpsMat(1.24e-6./value, material));
            pointnIm = imag(sqrt(EpsMat(1.24e-6./value, material)));%-pointEpsRe/2 + sqrt(pointEpsRe^2 + pointEpsIm^2)/2;
            
            pointDepth = value / (4*pi*pointnIm);
            
            app.EpsRe.Value = pointEpsRe;
            app.EpsIm.Value = pointEpsIm;
            app.Delta.Value = pointDepth * 1e9;
            
            %-------------Plotting Eps--------------------------- 
            % clear axes
            cla(app.UIAxesEps)
            hold(app.UIAxesEps, 'on');
            % plotting
            app.UIAxesEps.XLim = [wlMin wlMax] * 1e9;
            % zero level
            plot(app.UIAxesEps, wlSpace*1e9, 0.*epsIm,'k-','linewidth', 0.5, 'HandleVisibility', 'off');
            % main curves
            plot(app.UIAxesEps, wlSpace*1e9, epsRe,'k-','linewidth', 1, 'DisplayName','Re');
            plot(app.UIAxesEps, wlSpace*1e9, epsIm,'k--','linewidth', 1, 'DisplayName','Im');
            % circles
            plot(app.UIAxesEps, [app.WavelengthBox.Value app.WavelengthBox.Value], [pointEpsRe pointEpsIm], 'ro', 'MarkerFaceColor','r', 'DisplayName','Point')
            %plot(app.UIAxesEps, app.WavelengthBox.Value, pointEpsIm, 'ro', 'MarkerFaceColor','r', 'DisplayName','Point')
            
            legend(app.UIAxesEps)
            
            %-------------Plotting delta--------------------------- 
            % clear axes
            cla(app.UIAxesDelta)
            hold(app.UIAxesDelta, 'on');
            % plotting
            app.UIAxesDelta.XLim = [wlMin wlMax] * 1e9;
            % main curve
            plot(app.UIAxesDelta, wlSpace*1e9, delta * 1e9, 'k-', 'linewidth', 1, 'DisplayName','\delta');
            % circles
            plot(app.UIAxesDelta, app.WavelengthBox.Value, pointDepth * 1e9, 'ro', 'MarkerFaceColor','r', 'DisplayName','Point')
            legend(app.UIAxesDelta)
        end

        % Callback function
        function WavelengthBoxValueChanged(app, event)
            startupFcn(app)
        end

        % Callback function
        function WavelengthSliderValueChanged(app, event)
            app.WavelengthBox.Value = app.WavelengthSlider.Value;
            startupFcn(app)
        end

        % Callback function
        function WavelengthSliderValueChanging(app, event)
            app.WavelengthBox.Value = event.Value;
            startupFcn(app)
        end

        % Value changed function: Material
        function MaterialValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: WLMIN
        function WLMINValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: WLMAX
        function WLMAXValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: WavelengthBox
        function WavelengthBoxValueChanged2(app, event)
            startupFcn(app)
        end

        % Value changing function: WavelengthBox
        function WavelengthBoxValueChanging(app, event)
            startupFcn(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesEps = uiaxes(app.UIFigure);
            xlabel(app.UIAxesEps, 'Wavelength, \lambda (nm)')
            ylabel(app.UIAxesEps, 'Permittivity, \epsilon')
            app.UIAxesDelta = uiaxes(app.UIFigure);
            xlabel(app.UIAxesDelta, 'Wavelength, \lambda (nm)')
            ylabel(app.UIAxesDelta, 'Skin depth, \delta (nm)')
            app.SkindepthcalculatorLabel = uilabel(app.UIFigure);
            app.SkindepthcalculatorLabel.Text = 'Skin depth calculator';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = {'Object material'; 'ε(ω)​​'};
            app.Material = uidropdown(app.UIFigure);
            app.Material.Items = {'Gold', 'Silver', 'Copper', 'Aluminum', 'Silicon'};
            app.Material.ValueChangedFcn = createCallbackFcn(app, @MaterialValueChanged, true);
            app.Material.Value = 'Gold';
            app.SpecificpointPanel = uipanel(app.UIFigure);
            app.SpecificpointPanel.Title = 'Specific point';
            app.Label = uilabel(app.SpecificpointPanel);
            app.Label.Text = 'ε'' = ';
            app.EpsRe = uieditfield(app.SpecificpointPanel, 'numeric');
            app.EpsRe.Editable = 'off';
            app.MaterialpermittivityiLabel = uilabel(app.SpecificpointPanel);
            app.MaterialpermittivityiLabel.Text = 'Material permittivity     ε = ε'' + i ε''''';
            app.Label_2 = uilabel(app.SpecificpointPanel);
            app.Label_2.Text = 'ε'''' = ';
            app.EpsIm = uieditfield(app.SpecificpointPanel, 'numeric');
            app.EpsIm.Editable = 'off';
            app.Skindepth1Label = uilabel(app.SpecificpointPanel);
            app.Skindepth1Label.Text = 'Skin depth     δ = 1/ϰ  ';
            app.Label_3 = uilabel(app.SpecificpointPanel);
            app.Label_3.Text = 'δ = ';
            app.Delta = uieditfield(app.SpecificpointPanel, 'numeric');
            app.Delta.Editable = 'off';
            app.nmLabel = uilabel(app.SpecificpointPanel);
            app.nmLabel.Text = 'nm';
            app.WavelengthLabel = uilabel(app.SpecificpointPanel);
            app.WavelengthLabel.Text = 'Wavelength    λ =';
            app.WavelengthBox = uispinner(app.SpecificpointPanel);
            app.WavelengthBox.ValueChangingFcn = createCallbackFcn(app, @WavelengthBoxValueChanging, true);
            app.WavelengthBox.Limits = [20 2000];
            app.WavelengthBox.ValueChangedFcn = createCallbackFcn(app, @WavelengthBoxValueChanged2, true);
            app.WavelengthBox.Value = 300;
            app.nmLabel_2 = uilabel(app.SpecificpointPanel);
            app.nmLabel_2.Text = 'nm';
            app.ContactinformationMenu = uimenu(app.UIFigure);
            app.ContactinformationMenu.Text = 'Contact information';
            app.toftulivangmailcomMenu = uimenu(app.ContactinformationMenu);
            app.toftulivangmailcomMenu.Text = 'toftul.ivan@gmail.com';
            app.CreatedbyIvanToftulLabel = uilabel(app.UIFigure);
            app.CreatedbyIvanToftulLabel.Text = 'Created by Ivan Toftul';
            app.PlotrangeLabel = uilabel(app.UIFigure);
            app.PlotrangeLabel.Text = 'Plot range';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.WLMIN = uieditfield(app.UIFigure, 'numeric');
            app.WLMIN.Limits = [20 2000];
            app.WLMIN.ValueChangedFcn = createCallbackFcn(app, @WLMINValueChanged, true);
            app.WLMIN.Value = 250;
            app.MinEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MinEditFieldLabel_2.Text = 'nm';
            app.MinEditFieldLabel_3 = uilabel(app.UIFigure);
            app.MinEditFieldLabel_3.Text = 'nm';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.WLMAX = uieditfield(app.UIFigure, 'numeric');
            app.WLMAX.Limits = [20 2000];
            app.WLMAX.ValueChangedFcn = createCallbackFcn(app, @WLMAXValueChanged, true);
            app.WLMAX.Value = 1500;
        end
    end

    methods (Access = public)

        % Construct app
        function app = SkinDepth

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end